classdef conTPL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        TabGroup                    matlab.ui.container.TabGroup
        QutificationTab             matlab.ui.container.Tab
        DefaultPanel                matlab.ui.container.Panel
        NexttButton                 matlab.ui.control.Button
        Runto20Button               matlab.ui.control.Button
        BackwardTMLabel             matlab.ui.control.Label
        TMDropDown_backward         matlab.ui.control.DropDown
        ForwardTMLabel              matlab.ui.control.Label
        TMDropDown_forward          matlab.ui.control.DropDown
        EpsilonattEditFieldLabel    matlab.ui.control.Label
        EpsilonattEditField         matlab.ui.control.NumericEditField
        CustomizedPanel             matlab.ui.container.Panel
        NextTimeButton              matlab.ui.control.Button
        Runto20sButton              matlab.ui.control.Button
        BackwardSEditFieldLabel     matlab.ui.control.Label
        BackwardSEditField          matlab.ui.control.NumericEditField
        BackwardNEditFieldLabel     matlab.ui.control.Label
        BackwardNEditField          matlab.ui.control.NumericEditField
        EpsilonattEditField_2Label  matlab.ui.control.Label
        CusEpsilonattEditField      matlab.ui.control.NumericEditField
        ForwardSEditFieldLabel      matlab.ui.control.Label
        ForwardSEditField           matlab.ui.control.NumericEditField
        ForwardNEditFieldLabel      matlab.ui.control.Label
        ForwardNEditField           matlab.ui.control.NumericEditField
        V2Tab                       matlab.ui.container.Tab
        ClearAllButton              matlab.ui.control.Button
    end


    properties (Access = public)
        % Default Tab
        dim_n = 50; % Description
        backwardTM = [0.8,0.2; 0,1];
        forwardTM = [0.8,0.2; 0,1];
        eps = 0.1;
        backwardPL = 0;
        forwardPL = 0;
        time = 20;
        
        isFirstRun = 1;
        % action on 'Next t' button: step by step
        backwardPLStep = 0;
        forwardPLStep = 0;
        timeSteps = 0;
        BPLSteps = [];
        FPLSteps = [];
        tplSteps = [];
        epsSteps = [];
        initS = 0;
% % % % % % % % % %         
        %customized tab
        edtBackwardS = 0;
        edtBackwardN = 50;
        edtForwardS = 0;
        edtForwardN = 50;
        cusEps = 0.1;
        cusBackwardTM = [0.8,0.2; 0,1];
        cusForwardTM = [0.8,0.2; 0,1];
        cusBackwardPL = 0;
        cusForwardPL = 0;
        cusTime = 20;
        
        cusBackwardPLStep = 0;
        cusForwardPLStep = 0;
        cusTimeSteps = 0;
        cusBPLSteps = [];
        cusFPLSteps = [];
        cusTPLSteps = [];
        cusEpsSteps = [];
        
    end

    properties (Access = private)
        libpath = addpath('tools/'); % Description
        
    end

    methods (Access = public)
    
        function [res_TM] = genTM(app, s, n)
            res_TM = diag(ones(n,1))+s;
            res_TM = res_TM+s;
            res_TM = res_TM./sum(res_TM,2);
            
        end
    
    end

    methods (Access = private)
    
        function [newPL]=calcPL(app, pl, e, EspMatrix, qM, dM)
            % calc by precomputation
            [incrementalPL, val1, val2] = calcPLbyPreComp(pl, EspMatrix, qM, dM);
            newPL = incrementalPL+ e;
        end
        
        function resetValues(app)
            app.BPLSteps = [];
            app.timeSteps = 0;
            app.PLSteps = 0;
        end
        
    end


    methods (Access = private)

        % Value changed function: TMDropDown_backward
        function TMDropDown_backwardValueChanged(app, event)
            app.isFirstRun = 0;
            value = app.TMDropDown_backward.Value;
            app.backwardTM = genTM(app,str2double(value),app.dim_n);
            
        end

        % Value changed function: TMDropDown_forward
        function TMDropDown_forwardValueChanged(app, event)
            app.isFirstRun = 0;
            value = app.TMDropDown_forward.Value;
            app.forwardTM = genTM(app,str2double(value),app.dim_n);
            
        end

        % Value changed function: EpsilonattEditField
        function EpsilonattEditFieldValueChanged(app, event)
            value = app.EpsilonattEditField.Value;
            app.eps = value;
%             disp(value);
        end

        % Button pushed function: NexttButton
        function NexttButtonPushed(app, event)
            if app.isFirstRun == 1
                app.backwardTM = genTM(app,app.initS,app.dim_n);
                app.forwardTM = genTM(app,app.initS,app.dim_n);
            end
            
            [backwardEspMatrix, backwardQM, backwardDM, backwardTemp]= preCompQDMatrix(app.backwardTM);
            [forwardEspMatrix, forwardQM, forwardDM, forwardTemp]= preCompQDMatrix(app.forwardTM);
            bpls = [];
            fpls = [];
            epsilons = [];
            tpl = 0;
            
%             app.backwardPL = 0;
%             app.forwardPL = 0;
            app.tplSteps = [];
            
            app.timeSteps = app.timeSteps + 1;
            
            app.backwardPLStep = calcPL(app, app.backwardPLStep, app.eps, backwardEspMatrix, backwardQM, backwardDM);
            app.forwardPLStep = calcPL(app, app.forwardPLStep, app.eps, forwardEspMatrix, forwardQM, forwardDM);
            
            app.BPLSteps = [app.BPLSteps app.backwardPLStep];
            app.FPLSteps = [app.FPLSteps app.forwardPLStep];
           
            bpls = app.BPLSteps;
            fpls = app.FPLSteps;
            
            fpls = fliplr(fpls);
            
            for n = 1:app.timeSteps
                tpl = bpls(n) + fpls(n) - app.eps;
                app.tplSteps = [app.tplSteps tpl];
            end
            
%             disp('timeSteps');
%             disp(app.timeSteps);
%             disp(fpls);
%             disp(fpls(app.timeSteps));
%             tpl = app.backwardPLStep + fpls(app.timeSteps) - app.eps;
            
            
            app.epsSteps = [app.epsSteps app.eps];
            epsilons = app.epsSteps;
            times = 1:1:app.timeSteps;
            
            timeRange = 1:1:20;
            defaultV = zeros(1,20);
            defaultY = zeros(1, 21);
            yRange = 0:0.1:2;
%             app.UIAxes.XTicks([1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20]);
            app.UIAxes.NextPlot = 'replace';
            plotX = plot(app.UIAxes, timeRange, defaultV,'-b');
            hold(app.UIAxes);
            plotY = plot(app.UIAxes, defaultY, yRange,'-b');
            plotBPL = plot(app.UIAxes, times, bpls,'-o');
            
            plotFPL = plot(app.UIAxes, times, fpls,'-*');

            plotTPL = plot(app.UIAxes, times, app.tplSteps,'-^');
            plotEps = plot(app.UIAxes, times, epsilons, '--');
            legend(app.UIAxes,[plotBPL plotFPL plotTPL plotEps],'BPL','FPL','TPL','Epsilon');
        end

        % Button pushed function: NextTimeButton
        function NextTimeButtonPushed(app, event)
            app.cusBackwardTM = genTM(app,app.edtBackwardS,app.edtBackwardN);
            app.cusForwardTM = genTM(app,app.edtForwardS,app.edtForwardN);
            
            [backwardEspMatrix, backwardQM, backwardDM, backwardTemp]= preCompQDMatrix(app.cusBackwardTM);
            [forwardEspMatrix, forwardQM, forwardDM, forwardTemp]= preCompQDMatrix(app.cusForwardTM);
            
            bpls = [];
            fpls = [];
            epsilons = [];
            tpl = 0;
            app.cusTPLSteps = [];
            
%             app.cusBackwardPL = 0;
%             app.cusForwardPL = 0;
            
            app.cusTimeSteps = app.cusTimeSteps + 1;
            
            app.cusBackwardPLStep = calcPL(app, app.cusBackwardPLStep, app.cusEps, backwardEspMatrix, backwardQM, backwardDM);
            app.cusForwardPLStep = calcPL(app, app.cusForwardPLStep, app.cusEps, forwardEspMatrix, forwardQM, forwardDM);
            
            app.cusBPLSteps = [app.cusBPLSteps app.cusBackwardPLStep];
            app.cusFPLSteps = [app.cusFPLSteps app.cusForwardPLStep];
            
            bpls = app.cusBPLSteps;
            fpls = app.cusFPLSteps;
            fpls = fliplr(fpls);
            
            for n = 1: app.cusTimeSteps
                tpl = bpls(n) + fpls(n) - app.cusEps;
                app.cusTPLSteps = [app.cusTPLSteps tpl];
            end
%             tpl = app.cusBackwardPLStep + app.cusForwardPLStep - app.cusEps;
            
            
            app.cusEpsSteps = [app.cusEpsSteps,app.cusEps];
            epsilons = app.cusEpsSteps;


            times = 1:1:app.cusTimeSteps;
            
            timeRange = 1:1:20;
            defaultV = zeros(1,20);
            defaultY = zeros(1, 21);
            yRange = 0:0.1:2;
%             app.UIAxes.XTicks([1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20]);
            app.UIAxes.NextPlot = 'replace';
            plotX = plot(app.UIAxes, timeRange, defaultV,'-b');
            hold(app.UIAxes);
            plotY = plot(app.UIAxes, defaultY, yRange,'-b');
            plotBPL = plot(app.UIAxes, times, bpls,'-o');
            
            plotFPL = plot(app.UIAxes, times, fpls,'-*');

            plotTPL = plot(app.UIAxes, times, app.cusTPLSteps,'-^');
            plotEps = plot(app.UIAxes, times, epsilons, '--');
            legend(app.UIAxes,[plotBPL plotFPL plotTPL plotEps],'BPL','FPL','TPL','Epsilon');
            
        end

        % Button pushed function: Runto20sButton
        function Runto20sButtonPushed(app, event)
            app.cusBackwardTM = genTM(app,app.edtBackwardS,app.edtBackwardN);
            app.cusForwardTM = genTM(app,app.edtForwardS,app.edtForwardN);
            
            [backwardEspMatrix, backwardQM, backwardDM, backwardTemp]= preCompQDMatrix(app.cusBackwardTM);
            [forwardEspMatrix, forwardQM, forwardDM, forwardTemp]= preCompQDMatrix(app.cusForwardTM);
            
            bpls = [];
            fpls = [];
            tpls = [];
            epsilons = [];
            tpl = 0;
            app.cusBackwardPL = 0;
            app.cusForwardPL = 0;
        
            for t = 1:app.cusTime
                app.cusBackwardPL = calcPL(app, app.cusBackwardPL, app.cusEps, backwardEspMatrix, backwardQM, backwardDM);
                app.cusForwardPL = calcPL(app, app.cusForwardPL, app.cusEps, forwardEspMatrix, forwardQM, forwardDM);
                bpls = [bpls app.cusBackwardPL];
                fpls = [fpls app.cusForwardPL];
                epsilons = [epsilons app.cusEps];
            end
            
            fpls = fliplr(fpls);
            
            for cnt = 1:app.cusTime
                tpl = bpls(cnt) + fpls(cnt) - app.cusEps;
                tpls = [tpls tpl];
            end
            
            times = 1:1:app.cusTime;
            
            app.UIAxes.NextPlot = 'replace';
            plotBPL = plot(app.UIAxes, times, bpls,'-o');
            hold(app.UIAxes);
            plotFPL = plot(app.UIAxes, times, fpls,'-*');
%             hold(app.UIAxes);
            plotTPL = plot(app.UIAxes, times, tpls,'-^');
            plotEps = plot(app.UIAxes, times, epsilons, '--');
            legend(app.UIAxes,[plotBPL plotFPL plotTPL plotEps],'BPL','FPL','TPL','Epsilon');
            
        end

        % Value changed function: CusEpsilonattEditField
        function CusEpsilonattEditFieldValueChanged(app, event)
            value = app.CusEpsilonattEditField.Value;
            app.cusEps = value;
%             disp('app.cusEps');
%             disp(app.cusEps);
        end

        % Callback function: BackwardNEditField, BackwardSEditField, 
        % ForwardNEditField, ForwardSEditField
        function BackwardSEditFieldValueChanged(app, event)
            
            app.edtBackwardS = app.BackwardSEditField.Value;
            app.edtBackwardN = app.BackwardNEditField.Value;
            app.edtForwardS = app.ForwardSEditField.Value;
            app.edtForwardN = app.ForwardNEditField.Value;
%             disp('====');
%             disp(app.edtBackwardS);
%             disp(app.edtBackwardN);
%             disp(app.edtForwardS);
%             disp(app.edtForwardN);
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.dim_n = 50; % Description
            app.backwardTM = [0.8,0.2; 0,1];
            app.forwardTM = [0.8,0.2; 0,1];
            app.eps = 0.1;
            app.backwardPL = 0;
            app.forwardPL = 0;
            app.time = 20;
            
            app.isFirstRun = 1;
            % action on 'Next t' button: step by step
            app.backwardPLStep = 0;
            app.forwardPLStep = 0;
            app.timeSteps = 0;
            app.BPLSteps = [];
            app.FPLSteps = [];
            app.tplSteps = [];
            app.epsSteps = [];
            app.initS = 0;
    % % % % % % % % % %         
            %customized tab
            app.edtBackwardS = 0;
            app.edtBackwardN = 50;
            app.edtForwardS = 0;
            app.edtForwardN = 50;
            app.cusEps = 0.1;
            app.cusBackwardTM = [0.8,0.2; 0,1];
            app.cusForwardTM = [0.8,0.2; 0,1];
            app.cusBackwardPL = 0;
            app.cusForwardPL = 0;
            app.cusTime = 20;
            
            app.cusBackwardPLStep = 0;
            app.cusForwardPLStep = 0;
            app.cusTimeSteps = 0;
            app.cusBPLSteps = [];
            app.cusFPLSteps = [];
            app.cusTPLSteps = [];
            app.cusEpsSteps = [];
            
            % reset all UI panels
            app.TMDropDown_backward.Value = '0';
            app.TMDropDown_forward.Value = '0';
            app.EpsilonattEditField.Value = 0.1;
            app.BackwardSEditField.Value = 0;
            app.BackwardNEditField.Value = 50;
            app.ForwardSEditField.Value = 0;
            app.ForwardNEditField.Value = 50;
            app.CusEpsilonattEditField.Value = 0.1;
            
            cla(app.UIAxes);
            timeRange = 1:1:20;
            defaultV = zeros(1,20);
            defaultY = zeros(1, 21);
            yRange = 0:0.1:2;
%             app.UIAxes.XTicks([1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20]);
            app.UIAxes.NextPlot = 'replace';
            plot(app.UIAxes, timeRange, defaultV,'-b');
            hold(app.UIAxes);
            plot(app.UIAxes, defaultY, yRange,'-b');
        end

        % Button pushed function: Runto20Button
        function Runto20ButtonPushed(app, event)
            % first time run
            if app.isFirstRun == 1
                app.backwardTM = genTM(app,app.initS,app.dim_n);
                app.forwardTM = genTM(app,app.initS,app.dim_n);
            end
            
            [backwardEspMatrix, backwardQM, backwardDM, backwardTemp]= preCompQDMatrix(app.backwardTM);
            [forwardEspMatrix, forwardQM, forwardDM, forwardTemp]= preCompQDMatrix(app.forwardTM);
            bpls = [];
            fpls = [];
            epsilons = [];
            tpl = 0;
            tpls = [];
            app.backwardPL = 0;
            app.forwardPL = 0;
            
            for t = 1:app.time
                app.backwardPL = calcPL(app, app.backwardPL, app.eps, backwardEspMatrix, backwardQM, backwardDM);
                app.forwardPL = calcPL(app, app.forwardPL, app.eps, forwardEspMatrix, forwardQM, forwardDM);
                bpls = [bpls app.backwardPL];
                fpls = [fpls app.forwardPL];
                epsilons = [epsilons app.eps];
            end
            
            fpls = fliplr(fpls);
            
            for cnt = 1:app.time
                tpl = bpls(cnt) + fpls(cnt) - app.eps;
                tpls = [tpls tpl];
            end
            
            times = 1:1:app.time;
            
            app.UIAxes.NextPlot = 'replace';
            plotBPL = plot(app.UIAxes, times, bpls,'-o');
            hold(app.UIAxes);
            plotFPL = plot(app.UIAxes, times, fpls,'-*');
            plotTPL = plot(app.UIAxes, times, tpls,'-^');
            plotEps = plot(app.UIAxes, times, epsilons, '--');
            legend(app.UIAxes,[plotBPL plotFPL plotTPL plotEps],'BPL','FPL','TPL','Epsilon');
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Privacy Loss')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.UIAxes.XTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'};
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.ZTick = [0 0.5 1];
            app.TabGroup = uitabgroup(app.UIFigure);
            app.QutificationTab = uitab(app.TabGroup);
            app.QutificationTab.Title = 'Qutification';
            app.DefaultPanel = uipanel(app.QutificationTab);
            app.DefaultPanel.Title = 'Default';
            app.NexttButton = uibutton(app.DefaultPanel, 'push');
            app.NexttButton.ButtonPushedFcn = createCallbackFcn(app, @NexttButtonPushed, true);
            app.NexttButton.Text = 'Next t';
            app.Runto20Button = uibutton(app.DefaultPanel, 'push');
            app.Runto20Button.ButtonPushedFcn = createCallbackFcn(app, @Runto20ButtonPushed, true);
            app.Runto20Button.Text = 'Run to 20';
            app.BackwardTMLabel = uilabel(app.DefaultPanel);
            app.BackwardTMLabel.Text = 'Backward TM';
            app.TMDropDown_backward = uidropdown(app.DefaultPanel);
            app.TMDropDown_backward.Items = {'TM1', 'TM2', 'TM3'};
            app.TMDropDown_backward.ItemsData = {'0', '0.005', '0.05'};
            app.TMDropDown_backward.ValueChangedFcn = createCallbackFcn(app, @TMDropDown_backwardValueChanged, true);
            app.TMDropDown_backward.Value = '0';
            app.ForwardTMLabel = uilabel(app.DefaultPanel);
            app.ForwardTMLabel.Text = 'Forward TM';
            app.TMDropDown_forward = uidropdown(app.DefaultPanel);
            app.TMDropDown_forward.Items = {'TM1', 'TM2', 'TM3'};
            app.TMDropDown_forward.ItemsData = {'0', '0.005', '0.05'};
            app.TMDropDown_forward.ValueChangedFcn = createCallbackFcn(app, @TMDropDown_forwardValueChanged, true);
            app.TMDropDown_forward.Value = '0';
            app.EpsilonattEditFieldLabel = uilabel(app.DefaultPanel);
            app.EpsilonattEditFieldLabel.Text = 'Epsilon at t';
            app.EpsilonattEditField = uieditfield(app.DefaultPanel, 'numeric');
            app.EpsilonattEditField.ValueChangedFcn = createCallbackFcn(app, @EpsilonattEditFieldValueChanged, true);
            app.EpsilonattEditField.Value = 0.1;
            app.CustomizedPanel = uipanel(app.QutificationTab);
            app.CustomizedPanel.Title = 'Customized';
            app.NextTimeButton = uibutton(app.CustomizedPanel, 'push');
            app.NextTimeButton.ButtonPushedFcn = createCallbackFcn(app, @NextTimeButtonPushed, true);
            app.NextTimeButton.Text = 'Next Time';
            app.Runto20sButton = uibutton(app.CustomizedPanel, 'push');
            app.Runto20sButton.ButtonPushedFcn = createCallbackFcn(app, @Runto20sButtonPushed, true);
            app.Runto20sButton.Text = 'Run to 20s';
            app.BackwardSEditFieldLabel = uilabel(app.CustomizedPanel);
            app.BackwardSEditFieldLabel.Text = 'Backward S';
            app.BackwardSEditField = uieditfield(app.CustomizedPanel, 'numeric');
            app.BackwardSEditField.Limits = [0 Inf];
            app.BackwardSEditField.ValueChangedFcn = createCallbackFcn(app, @BackwardSEditFieldValueChanged, true);
            app.BackwardNEditFieldLabel = uilabel(app.CustomizedPanel);
            app.BackwardNEditFieldLabel.Text = 'Backward N';
            app.BackwardNEditField = uieditfield(app.CustomizedPanel, 'numeric');
            app.BackwardNEditField.Limits = [0 Inf];
            app.BackwardNEditField.ValueChangedFcn = createCallbackFcn(app, @BackwardSEditFieldValueChanged, true);
            app.BackwardNEditField.Value = 50;
            app.EpsilonattEditField_2Label = uilabel(app.CustomizedPanel);
            app.EpsilonattEditField_2Label.Text = 'Epsilon at t';
            app.CusEpsilonattEditField = uieditfield(app.CustomizedPanel, 'numeric');
            app.CusEpsilonattEditField.Limits = [0 Inf];
            app.CusEpsilonattEditField.ValueChangedFcn = createCallbackFcn(app, @CusEpsilonattEditFieldValueChanged, true);
            app.CusEpsilonattEditField.Value = 0.1;
            app.ForwardSEditFieldLabel = uilabel(app.CustomizedPanel);
            app.ForwardSEditFieldLabel.Text = 'Forward S';
            app.ForwardSEditField = uieditfield(app.CustomizedPanel, 'numeric');
            app.ForwardSEditField.Limits = [0 Inf];
            app.ForwardSEditField.ValueChangedFcn = createCallbackFcn(app, @BackwardSEditFieldValueChanged, true);
            app.ForwardNEditFieldLabel = uilabel(app.CustomizedPanel);
            app.ForwardNEditFieldLabel.Text = 'Forward N';
            app.ForwardNEditField = uieditfield(app.CustomizedPanel, 'numeric');
            app.ForwardNEditField.Limits = [0 Inf];
            app.ForwardNEditField.ValueChangedFcn = createCallbackFcn(app, @BackwardSEditFieldValueChanged, true);
            app.ForwardNEditField.Value = 50;
            app.V2Tab = uitab(app.TabGroup);
            app.V2Tab.Title = 'V2';
            app.ClearAllButton = uibutton(app.UIFigure, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
        end
    end

    methods (Access = public)

        % Construct app
        function app = conTPL

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end