classdef conTPL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        TabGroup                    matlab.ui.container.TabGroup
        QutificationTab             matlab.ui.container.Tab
        DefaultPanel                matlab.ui.container.Panel
        NexttButton                 matlab.ui.control.Button
        Runto20Button               matlab.ui.control.Button
        BackwardTMLabel             matlab.ui.control.Label
        TMDropDown_backward         matlab.ui.control.DropDown
        ForwardTMLabel              matlab.ui.control.Label
        TMDropDown_forward          matlab.ui.control.DropDown
        EpsilonattEditFieldLabel    matlab.ui.control.Label
        EpsilonattEditField         matlab.ui.control.NumericEditField
        backwardTM_label            matlab.ui.control.Label
        forwardTM_label             matlab.ui.control.Label
        CustomizedPanel             matlab.ui.container.Panel
        NextTimeButton              matlab.ui.control.Button
        Runto20sButton              matlab.ui.control.Button
        BackwardSEditFieldLabel     matlab.ui.control.Label
        BackwardSEditField          matlab.ui.control.NumericEditField
        BackwardNEditFieldLabel     matlab.ui.control.Label
        BackwardNEditField          matlab.ui.control.NumericEditField
        EpsilonattEditField_2Label  matlab.ui.control.Label
        CusEpsilonattEditField      matlab.ui.control.NumericEditField
        ForwardSEditFieldLabel      matlab.ui.control.Label
        ForwardSEditField           matlab.ui.control.NumericEditField
        ForwardNEditFieldLabel      matlab.ui.control.Label
        ForwardNEditField           matlab.ui.control.NumericEditField
        ConTPLTab                   matlab.ui.container.Tab
        BackwardTMDropDownLabel     matlab.ui.control.Label
        BackwardTMDropDown          matlab.ui.control.DropDown
        ForwardTMDropDownLabel      matlab.ui.control.Label
        ForwardTMDropDown           matlab.ui.control.DropDown
        NextButton                  matlab.ui.control.Button
        ButtonGroup                 matlab.ui.container.ButtonGroup
        Method1Button               matlab.ui.control.RadioButton
        Method2Button               matlab.ui.control.RadioButton
        Method3Button               matlab.ui.control.RadioButton
        MaxTPLEditFieldLabel        matlab.ui.control.Label
        MaxTPLEditField_method1     matlab.ui.control.NumericEditField
        TEditFieldLabel             matlab.ui.control.Label
        TEditField                  matlab.ui.control.NumericEditField
        MaxTPLEditField_2Label      matlab.ui.control.Label
        MaxTPLEditField_method2     matlab.ui.control.NumericEditField
        MaxBPLtEditFieldLabel       matlab.ui.control.Label
        MaxBPLtEditField_method3    matlab.ui.control.NumericEditField
        TM1StrongTemporalCorrelationLabel  matlab.ui.control.Label
        TM1StrongTemporalCorrelationLabel_2  matlab.ui.control.Label
        errorMsg                    matlab.ui.control.Label
        ClearAllButton              matlab.ui.control.Button
    end


    properties (Access = public)
        
        % Settings
        strongTM = 0;
        moderateTM = 0.01;
        weakTM = 0.1;
        % Default Tab
        dim_n = 30; % Description
        backwardTM = [0.8,0.2; 0,1];
        forwardTM = [0.8,0.2; 0,1];
        eps = 0.1;
        backwardPL = 0;
        forwardPL = 0;
        time = 20;
        
        isFirstRun = 1;
        % action on 'Next t' button: step by step
        backwardPLStep = 0;
        forwardPLStep = 0;
        timeSteps = 0;
        BPLSteps = [];
        FPLSteps = [];
        tplSteps = [];
        epsSteps = [];
        initS = 0;
% % % % % % % % % %         
        %customized tab
        edtBackwardS = 0;
        edtBackwardN = 30;
        edtForwardS = 0;
        edtForwardN = 30;
        cusEps = 0.1;
        cusBackwardTM = [0.8,0.2; 0,1];
        cusForwardTM = [0.8,0.2; 0,1];
        cusBackwardPL = 0;
        cusForwardPL = 0;
        cusTime = 20;
        
        cusBackwardPLStep = 0;
        cusForwardPLStep = 0;
        cusTimeSteps = 0;
        cusBPLSteps = [];
        cusFPLSteps = [];
        cusTPLSteps = [];
        cusEpsSteps = [];
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % conTPL
        conTPL_isDefault = 1;
        conTPL_isFirstRun = 0;
        conTPL_backwardTM = [0.8,0.2; 0,1];
        conTPL_forwardTM = [0.8,0.2; 0,1];
        conTPL_maxTPL_method1 = 1;
        conTPL_maxTPL_method2 = 1;
        conTPL_maxBPL_method3 = 1;
        conTPL_t = 20;
        conTPL_radioButtonsIdentifier = 'Method 1';
        conTPL_backwardPL = 0;
        conTPL_forwardPL = 0;
        conTPL_backwardStep = 0;
        conTPL_forwardStep = 0;
        conTPL_time = 0;
        conTPL_TPLSteps = [];
        conTPL_BPLSteps = [];
        conTPL_FPLSteps = [];
        conTPL_EpsSteps = [];
        conTPL_eps = 0.1;
        conTPL_eps_method3 = 0.1;
        conTPL_errCode = 0;
        
    end

    properties (Access = private)
        libpath = addpath('tools/'); % Description
        
    end

    methods (Access = public)
    
        function [res_TM] = genTM(app, s, n)
            res_TM = diag(ones(n,1))+s;
            res_TM = res_TM+s;
            res_TM = res_TM./sum(res_TM,2);
            
        end
    
    end

    methods (Access = private)
    
        function [newPL]=calcPL(app, pl, e, EspMatrix, qM, dM)
            % calc by precomputation
            [incrementalPL, val1, val2] = calcPLbyPreComp(pl, EspMatrix, qM, dM);
            newPL = incrementalPL+ e;
        end
        
        function resetValues(app)
            app.BPLSteps = [];
            app.timeSteps = 0;
            app.PLSteps = 0;
        end
        
        function [epsArr] = conTPL1(app,TM_B, TM_F, tpl_goal,T)
            [e_s, e_mid, e_end]=allocEspByQuantify(tpl_goal, TM_B, TM_F);
            eArr_mid=ones(1, T-2)*e_mid;
            epsArr=[e_s, eArr_mid, e_end];
        end
        
        function [eps, errCode] = conTPL2(app,TM_B, TM_F, tpl_goal)
%             addpath('tools/');
            [eps, errCode] = allocEspByUpperBound(tpl_goal, TM_B, TM_F);
        end
        
        function [e_t] = conBPL(app,bpl_previous, TM_B, bpl_t_goal)
            addpath('tools/');
            [EspMatrix, qM, dM, temp]= preCompQDMatrix(TM_B);
            [EspMatrix_B, qM_B, dM_B, QDplusInd_B]= preCompQDMatrix(TM_B);
            EspMatrix_B(isnan(EspMatrix_B)) = 0;
            
            a1=0;
            an=bpl_t_goal+1;
            [aArrMax_B, qArrMax_B, dArrMax_B] = genLFunc(a1, an, EspMatrix_B, qM_B, dM_B);
            
            % find a_B
            L_B=calcPLbyFunc(bpl_previous, aArrMax_B, qArrMax_B, dArrMax_B);
            e_t = bpl_t_goal - L_B;
            if e_t<0
                e_t = 'not possible';
            end
        end
        
        
        function results = conPTLPlot(app,times,bpls,fpls,tpls,epsilons)
            plotBPL = plot(app.UIAxes, times, bpls,'-o');
            
            plotFPL = plot(app.UIAxes, times, fpls,'-*');

            plotTPL = plot(app.UIAxes, times, tpls,'-^');
            plotEps = plot(app.UIAxes, times, epsilons, '--');
            set(plotBPL, 'linewidth', 2);
            set(plotFPL, 'linewidth', 2);
            set(plotTPL, 'linewidth', 2);
            set(plotEps, 'linewidth', 2);
            lgd = legend(app.UIAxes,[plotBPL plotFPL plotTPL plotEps],'BPL','FPL','TPL','Epsilon');
            lgd.FontSize = 15;
            app.UIAxes.XLabel.String = 'Time';
            app.UIAxes.YLabel.String = 'Privacy Loss';
            app.UIAxes.FontSize = 15;
            app.UIAxes.Title.String = 'ConTPL';
        end
        
    end


    methods (Access = private)

        % Value changed function: TMDropDown_backward
        function TMDropDown_backwardValueChanged(app, event)
            app.isFirstRun = 0;
            value = app.TMDropDown_backward.Value;
            app.backwardTM = genTM(app,str2double(value),app.dim_n);
            switch value
                case string(app.strongTM)
                    app.backwardTM_label.Text = 'TM1:  Strong Temporal Correlation';
                    app.backwardTM_label.FontColor = '[0.00,0.00,1.00]';
                case string(app.moderateTM)
                    app.backwardTM_label.Text = 'TM2:  Moderate Temporal Correlation';
                    app.backwardTM_label.FontColor = '[0.00,0.45,0.74]';
                case string(app.weakTM)
                    app.backwardTM_label.Text = 'TM3:  Weak Temporal Correlation';
                    app.backwardTM_label.FontColor = '[0.30,0.75,0.93]';
            end
            
        end

        % Value changed function: TMDropDown_forward
        function TMDropDown_forwardValueChanged(app, event)
            app.isFirstRun = 0;
            value = app.TMDropDown_forward.Value;
            app.forwardTM = genTM(app,str2double(value),app.dim_n);
            switch value
                case string(app.strongTM)
                    app.forwardTM_label.Text = 'TM1:  Strong Temporal Correlation';
                    app.forwardTM_label.FontColor = '[0.00,0.00,1.00]';
                case string(app.moderateTM)
                    app.forwardTM_label.Text = 'TM2:  Moderate Temporal Correlation';
                    app.forwardTM_label.FontColor = '[0.00,0.45,0.74]';
                case string(app.weakTM)
                    app.forwardTM_label.Text = 'TM3:  Weak Temporal Correlation';
                    app.forwardTM_label.FontColor = '[0.30,0.75,0.93]';
            end
            
        end

        % Value changed function: EpsilonattEditField
        function EpsilonattEditFieldValueChanged(app, event)
            value = app.EpsilonattEditField.Value;
            app.eps = value;
%             disp(value);
        end

        % Button pushed function: NexttButton
        function NexttButtonPushed(app, event)
            if app.isFirstRun == 1
                app.backwardTM = genTM(app,app.initS,app.dim_n);
                app.forwardTM = genTM(app,app.initS,app.dim_n);
            end
            
            [backwardEspMatrix, backwardQM, backwardDM, backwardTemp]= preCompQDMatrix(app.backwardTM);
            [forwardEspMatrix, forwardQM, forwardDM, forwardTemp]= preCompQDMatrix(app.forwardTM);
            bpls = [];
            fpls = [];
            epsilons = [];
            tpl = 0;
            
%             app.backwardPL = 0;
%             app.forwardPL = 0;
            app.tplSteps = [];
            
            app.timeSteps = app.timeSteps + 1;
            
            app.backwardPLStep = calcPL(app, app.backwardPLStep, app.eps, backwardEspMatrix, backwardQM, backwardDM);
            app.forwardPLStep = calcPL(app, app.forwardPLStep, app.eps, forwardEspMatrix, forwardQM, forwardDM);
            
            app.BPLSteps = [app.BPLSteps app.backwardPLStep];
            app.FPLSteps = [app.FPLSteps app.forwardPLStep];
           
            bpls = app.BPLSteps;
            fpls = app.FPLSteps;
            
            fpls = fliplr(fpls);
            
            for n = 1:app.timeSteps
                tpl = bpls(n) + fpls(n) - app.eps;
                app.tplSteps = [app.tplSteps tpl];
            end
            
%             disp('timeSteps');
%             disp(app.timeSteps);
%             disp(fpls);
%             disp(fpls(app.timeSteps));
%             tpl = app.backwardPLStep + fpls(app.timeSteps) - app.eps;
            
            
            app.epsSteps = [app.epsSteps app.eps];
            epsilons = app.epsSteps;
            times = 1:1:app.timeSteps;
            
            timeRange = 1:1:20;
            defaultV = zeros(1,20);
            defaultY = zeros(1, 21);
            yRange = 0:0.1:2;
%             app.UIAxes.XTicks([1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20]);
            app.UIAxes.NextPlot = 'replace';
            plotX = plot(app.UIAxes, timeRange, defaultV,'-b');
            hold(app.UIAxes);
            plotY = plot(app.UIAxes, defaultY, yRange,'-b');
            conPTLPlot(app,times,bpls,fpls,app.tplSteps,epsilons);
%             plotBPL = plot(app.UIAxes, times, bpls,'-o');
%             
%             plotFPL = plot(app.UIAxes, times, fpls,'-*');
% 
%             plotTPL = plot(app.UIAxes, times, app.tplSteps,'-^');
%             plotEps = plot(app.UIAxes, times, epsilons, '--');
%             set(plotBPL, 'linewidth', 2);
%             set(plotFPL, 'linewidth', 2);
%             set(plotTPL, 'linewidth', 2);
%             set(plotEps, 'linewidth', 2);
%             lgd = legend(app.UIAxes,[plotBPL plotFPL plotTPL plotEps],'BPL','FPL','TPL','Epsilon');
%             lgd.FontSize = 15;
%             app.UIAxes.XLabel.String = 'Time';
%             app.UIAxes.YLabel.String = 'Privacy Loss';
%             app.UIAxes.FontSize = 15;
%             app.UIAxes.Title.String = 'ConTPL';
        end

        % Button pushed function: NextTimeButton
        function NextTimeButtonPushed(app, event)
            app.cusBackwardTM = genTM(app,app.edtBackwardS,app.edtBackwardN);
            app.cusForwardTM = genTM(app,app.edtForwardS,app.edtForwardN);
            
            [backwardEspMatrix, backwardQM, backwardDM, backwardTemp]= preCompQDMatrix(app.cusBackwardTM);
            [forwardEspMatrix, forwardQM, forwardDM, forwardTemp]= preCompQDMatrix(app.cusForwardTM);
            
            bpls = [];
            fpls = [];
            epsilons = [];
            tpl = 0;
            app.cusTPLSteps = [];
            
%             app.cusBackwardPL = 0;
%             app.cusForwardPL = 0;
            
            app.cusTimeSteps = app.cusTimeSteps + 1;
            
            app.cusBackwardPLStep = calcPL(app, app.cusBackwardPLStep, app.cusEps, backwardEspMatrix, backwardQM, backwardDM);
            app.cusForwardPLStep = calcPL(app, app.cusForwardPLStep, app.cusEps, forwardEspMatrix, forwardQM, forwardDM);
            
            app.cusBPLSteps = [app.cusBPLSteps app.cusBackwardPLStep];
            app.cusFPLSteps = [app.cusFPLSteps app.cusForwardPLStep];
            
            bpls = app.cusBPLSteps;
            fpls = app.cusFPLSteps;
            fpls = fliplr(fpls);
            
            for n = 1: app.cusTimeSteps
                tpl = bpls(n) + fpls(n) - app.cusEps;
                app.cusTPLSteps = [app.cusTPLSteps tpl];
            end
%             tpl = app.cusBackwardPLStep + app.cusForwardPLStep - app.cusEps;
            
            
            app.cusEpsSteps = [app.cusEpsSteps,app.cusEps];
            epsilons = app.cusEpsSteps;


            times = 1:1:app.cusTimeSteps;
            
            timeRange = 1:1:20;
            defaultV = zeros(1,20);
            defaultY = zeros(1, 21);
            yRange = 0:0.1:2;
%             app.UIAxes.XTicks([1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20]);
            app.UIAxes.NextPlot = 'replace';
            plotX = plot(app.UIAxes, timeRange, defaultV,'-b');
            hold(app.UIAxes);
            plotY = plot(app.UIAxes, defaultY, yRange,'-b');
            conPTLPlot(app,times,bpls,fpls,app.cusTPLSteps,epsilons);
%             plotBPL = plot(app.UIAxes, times, bpls,'-o');
%             
%             plotFPL = plot(app.UIAxes, times, fpls,'-*');
% 
%             plotTPL = plot(app.UIAxes, times, app.cusTPLSteps,'-^');
%             plotEps = plot(app.UIAxes, times, epsilons, '--');
%             set(plotBPL, 'linewidth', 2);
%             set(plotFPL, 'linewidth', 2);
%             set(plotTPL, 'linewidth', 2);
%             set(plotEps, 'linewidth', 2);
%             lgd = legend(app.UIAxes,[plotBPL plotFPL plotTPL plotEps],'BPL','FPL','TPL','Epsilon');
%             lgd.FontSize = 15;
            
        end

        % Button pushed function: Runto20sButton
        function Runto20sButtonPushed(app, event)
            app.cusBackwardTM = genTM(app,app.edtBackwardS,app.edtBackwardN);
            app.cusForwardTM = genTM(app,app.edtForwardS,app.edtForwardN);
            
            [backwardEspMatrix, backwardQM, backwardDM, backwardTemp]= preCompQDMatrix(app.cusBackwardTM);
            [forwardEspMatrix, forwardQM, forwardDM, forwardTemp]= preCompQDMatrix(app.cusForwardTM);
            
            bpls = [];
            fpls = [];
            tpls = [];
            epsilons = [];
            tpl = 0;
            app.cusBackwardPL = 0;
            app.cusForwardPL = 0;
        
            for t = 1:app.cusTime
                app.cusBackwardPL = calcPL(app, app.cusBackwardPL, app.cusEps, backwardEspMatrix, backwardQM, backwardDM);
                app.cusForwardPL = calcPL(app, app.cusForwardPL, app.cusEps, forwardEspMatrix, forwardQM, forwardDM);
                bpls = [bpls app.cusBackwardPL];
                fpls = [fpls app.cusForwardPL];
                epsilons = [epsilons app.cusEps];
            end
            
            fpls = fliplr(fpls);
            
            for cnt = 1:app.cusTime
                tpl = bpls(cnt) + fpls(cnt) - app.cusEps;
                tpls = [tpls tpl];
            end
            
            times = 1:1:app.cusTime;
            
            app.UIAxes.NextPlot = 'replace';
            plotBPL = plot(app.UIAxes, times, bpls,'-o');
            hold(app.UIAxes);
            plotFPL = plot(app.UIAxes, times, fpls,'-*');
%             hold(app.UIAxes);
            plotTPL = plot(app.UIAxes, times, tpls,'-^');
            plotEps = plot(app.UIAxes, times, epsilons, '--');
            set(plotBPL, 'linewidth', 2);
            set(plotFPL, 'linewidth', 2);
            set(plotTPL, 'linewidth', 2);
            set(plotEps, 'linewidth', 2);
            lgd = legend(app.UIAxes,[plotBPL plotFPL plotTPL plotEps],'BPL','FPL','TPL','Epsilon');
            lgd.FontSize = 15;
            app.UIAxes.XLabel.String = 'Time';
            app.UIAxes.YLabel.String = 'Privacy Loss';
            app.UIAxes.FontSize = 15;
            app.UIAxes.Title.String = 'ConTPL';
            
        end

        % Value changed function: CusEpsilonattEditField
        function CusEpsilonattEditFieldValueChanged(app, event)
            value = app.CusEpsilonattEditField.Value;
            app.cusEps = value;
%             disp('app.cusEps');
%             disp(app.cusEps);
        end

        % Value changed function: BackwardNEditField, 
        % BackwardSEditField, ForwardNEditField, ForwardSEditField
        function BackwardSEditFieldValueChanged(app, event)
            
            app.edtBackwardS = app.BackwardSEditField.Value;
            app.edtBackwardN = app.BackwardNEditField.Value;
            app.edtForwardS = app.ForwardSEditField.Value;
            app.edtForwardN = app.ForwardNEditField.Value;
%             disp('====');
%             disp(app.edtBackwardS);
%             disp(app.edtBackwardN);
%             disp(app.edtForwardS);
%             disp(app.edtForwardN);
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            
            % settings
            app.strongTM = 0;
            app.moderateTM = 0.01;
            app.weakTM = 0.1;
            app.dim_n = 30; % Description
            app.backwardTM = [0.8,0.2; 0,1];
            app.forwardTM = [0.8,0.2; 0,1];
            app.eps = 0.1;
            app.backwardPL = 0;
            app.forwardPL = 0;
            app.time = 20;
            
            app.isFirstRun = 1;
            % action on 'Next t' button: step by step
            app.backwardPLStep = 0;
            app.forwardPLStep = 0;
            app.timeSteps = 0;
            app.BPLSteps = [];
            app.FPLSteps = [];
            app.tplSteps = [];
            app.epsSteps = [];
            app.initS = 0;
    % % % % % % % % % %         
            %customized tab
            app.edtBackwardS = 0;
            app.edtBackwardN = 30;
            app.edtForwardS = 0;
            app.edtForwardN = 30;
            app.cusEps = 0.1;
            app.cusBackwardTM = [0.8,0.2; 0,1];
            app.cusForwardTM = [0.8,0.2; 0,1];
            app.cusBackwardPL = 0;
            app.cusForwardPL = 0;
            app.cusTime = 20;
            
            app.cusBackwardPLStep = 0;
            app.cusForwardPLStep = 0;
            app.cusTimeSteps = 0;
            app.cusBPLSteps = [];
            app.cusFPLSteps = [];
            app.cusTPLSteps = [];
            app.cusEpsSteps = [];
            
            % conTPL
            app.conTPL_isDefault = 1;
            app.conTPL_isFirstRun = 0;
            app.conTPL_backwardTM = [0.8,0.2; 0,1];
            app.conTPL_forwardTM = [0.8,0.2; 0,1];
            app.conTPL_maxTPL_method1 = 1;
            app.conTPL_maxTPL_method2 = 1;
            app.conTPL_maxBPL_method3 = 1;
            app.conTPL_t = 20;
            app.conTPL_radioButtonsIdentifier = 'Method 1';
            app.conTPL_backwardPL = 0;
            app.conTPL_forwardPL = 0;
            app.conTPL_backwardStep = 0;
            app.conTPL_forwardStep = 0;
            app.conTPL_time = 0;
            app.conTPL_TPLSteps = [];
            app.conTPL_BPLSteps = [];
            app.conTPL_FPLSteps = [];
            app.conTPL_EpsSteps = [];
            app.conTPL_eps = 0.1;
            app.conTPL_eps_method3 = 0.1;
            app.conTPL_errCode = 0;
            
            
            % reset all UI panels
            app.TMDropDown_backward.Value = '0';
            app.TMDropDown_forward.Value = '0';
            app.EpsilonattEditField.Value = 0.1;
            app.BackwardSEditField.Value = 0;
            app.BackwardNEditField.Value = 30;
            app.ForwardSEditField.Value = 0;
            app.ForwardNEditField.Value = 30;
            app.CusEpsilonattEditField.Value = 0.1;
            
            app.BackwardTMDropDown.Value = '0';
            app.ForwardTMDropDown.Value = '0';
            app.Method1Button.Value = 1;
            app.MaxTPLEditField_method1.Enable = 'on';
            app.TEditField.Enable = 'on';
            app.MaxTPLEditField_method2.Enable = 'off';
            app.MaxBPLtEditField_method3.Enable = 'off';
            
            app.errorMsg.Visible = 'off';
            
            app.TM1StrongTemporalCorrelationLabel.Text = 'TM1:  Strong Temporal Correlation';
            app.TM1StrongTemporalCorrelationLabel.FontColor = '[0.00,0.00,1.00]';
            
            app.TM1StrongTemporalCorrelationLabel_2.Text = 'TM1:  Strong Temporal Correlation';
            app.TM1StrongTemporalCorrelationLabel_2.FontColor = '[0.00,0.00,1.00]';
            
            app.backwardTM_label.Text = 'TM1:  Strong Temporal Correlation';
            app.backwardTM_label.FontColor = '[0.00,0.00,1.00]';
            
            app.forwardTM_label.Text = 'TM1:  Strong Temporal Correlation';
            app.forwardTM_label.FontColor = '[0.00,0.00,1.00]';
            
            cla(app.UIAxes);
            timeRange = 1:1:20;
            defaultV = zeros(1,20);
            defaultY = zeros(1, 21);
            yRange = 0:0.1:2;
%             app.UIAxes.XTicks([1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20]);
            app.UIAxes.NextPlot = 'replace';
            plot(app.UIAxes, timeRange, defaultV,'-b');
            hold(app.UIAxes);
            plot(app.UIAxes, defaultY, yRange,'-b');
            app.UIAxes.XLabel.String = 'Time';
            app.UIAxes.YLabel.String = 'Privacy Loss';
            app.UIAxes.FontSize = 15;
            app.UIAxes.Title.String = 'ConTPL';
        end

        % Button pushed function: Runto20Button
        function Runto20ButtonPushed(app, event)
            % first time run
            if app.isFirstRun == 1
                app.backwardTM = genTM(app,app.initS,app.dim_n);
                app.forwardTM = genTM(app,app.initS,app.dim_n);
            end
            
            [backwardEspMatrix, backwardQM, backwardDM, backwardTemp]= preCompQDMatrix(app.backwardTM);
            [forwardEspMatrix, forwardQM, forwardDM, forwardTemp]= preCompQDMatrix(app.forwardTM);
            bpls = [];
            fpls = [];
            epsilons = [];
            tpl = 0;
            tpls = [];
            app.backwardPL = 0;
            app.forwardPL = 0;
            
            for t = 1:app.time
                app.backwardPL = calcPL(app, app.backwardPL, app.eps, backwardEspMatrix, backwardQM, backwardDM);
                app.forwardPL = calcPL(app, app.forwardPL, app.eps, forwardEspMatrix, forwardQM, forwardDM);
                bpls = [bpls app.backwardPL];
                fpls = [fpls app.forwardPL];
                epsilons = [epsilons app.eps];
            end
            
            fpls = fliplr(fpls);
            
            for cnt = 1:app.time
                tpl = bpls(cnt) + fpls(cnt) - app.eps;
                tpls = [tpls tpl];
            end
            
            times = 1:1:app.time;
            
            app.UIAxes.NextPlot = 'replace';
            plotBPL = plot(app.UIAxes, times, bpls,'-o');
            hold(app.UIAxes);
            plotFPL = plot(app.UIAxes, times, fpls,'-*');
            plotTPL = plot(app.UIAxes, times, tpls,'-^');
            plotEps = plot(app.UIAxes, times, epsilons, '--');
            set(plotBPL, 'linewidth', 2);
            set(plotFPL, 'linewidth', 2);
            set(plotTPL, 'linewidth', 2);
            set(plotEps, 'linewidth', 2);
            lgd = legend(app.UIAxes,[plotBPL plotFPL plotTPL plotEps],'BPL','FPL','TPL','Epsilon');
            lgd.FontSize = 15;
            app.UIAxes.XLabel.String = 'Time';
            app.UIAxes.YLabel.String = 'Privacy Loss';
            app.UIAxes.FontSize = 15;
            app.UIAxes.Title.String = 'ConTPL';
            
        end

        % Value changed function: BackwardTMDropDown
        function BackwardTMDropDownValueChanged(app, event)
            app.conTPL_isDefault = 0;
            value = app.BackwardTMDropDown.Value;
            app.conTPL_backwardTM = genTM(app,str2double(value),app.dim_n);
            switch value
                case string(app.strongTM)
                    app.TM1StrongTemporalCorrelationLabel.Text = 'TM1:  Strong Temporal Correlation';
                    app.TM1StrongTemporalCorrelationLabel.FontColor = '[0.00,0.00,1.00]';
                case string(app.moderateTM)
                    app.TM1StrongTemporalCorrelationLabel.Text = 'TM2:  Moderate Temporal Correlation';
                    app.TM1StrongTemporalCorrelationLabel.FontColor = '[0.00,0.45,0.74]';
                case string(app.weakTM)
                    app.TM1StrongTemporalCorrelationLabel.Text = 'TM3:  Weak Temporal Correlation';
                    app.TM1StrongTemporalCorrelationLabel.FontColor = '[0.30,0.75,0.93]';
            end
            
        end

        % Value changed function: ForwardTMDropDown
        function ForwardTMDropDownValueChanged(app, event)
            app.conTPL_isDefault = 0;
            value = app.ForwardTMDropDown.Value;
            app.conTPL_forwardTM = genTM(app,str2double(value),app.dim_n);
            switch value
                case string(app.strongTM)
                    app.TM1StrongTemporalCorrelationLabel_2.Text = 'TM1:  Strong Temporal Correlation';
                    app.TM1StrongTemporalCorrelationLabel_2.FontColor = '[0.00,0.00,1.00]';
                case string(app.moderateTM)
                    app.TM1StrongTemporalCorrelationLabel_2.Text = 'TM2:  Moderate Temporal Correlation';
                    app.TM1StrongTemporalCorrelationLabel_2.FontColor = '[0.00,0.45,0.74]';
                case string(app.weakTM)
                    app.TM1StrongTemporalCorrelationLabel_2.Text = 'TM3:  Weak Temporal Correlation';
                    app.TM1StrongTemporalCorrelationLabel_2.FontColor = '[0.30,0.75,0.93]';
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.conTPL_isDefault == 1
               app.conTPL_backwardTM = genTM(app,app.initS,app.dim_n);
               app.conTPL_forwardTM = genTM(app,app.initS,app.dim_n);
            end
            
            conTPL_bpls = [];
            conTPL_fpls = [];
            eps_method2 = 0;
            app.conTPL_isFirstRun = app.conTPL_isFirstRun + 1;
            disp('radio group next');
            disp(app.conTPL_radioButtonsIdentifier);
            switch app.conTPL_radioButtonsIdentifier
                case 'Method 1'
                    app.MaxTPLEditField_method1.Enable = 'off';
                    app.TEditField.Enable = 'off';
                    if app.conTPL_isFirstRun == 1
                        app.conTPL_EpsSteps = conTPL1(app,app.conTPL_backwardTM, app.conTPL_forwardTM, app.conTPL_maxTPL_method1,app.conTPL_t);
                    end
                    
%                     conTPL_bpls = conTPL1(app,app.conTPL_backwardTM, app.conTPL_forwardTM, app.conTPL_maxTPL_method1,app.conTPL_t);
%                     disp('conTPL_bpls-->');
%                     disp(conTPL_bpls);
%                     disp(app.initS);
%                     disp(app.dim_n);
%                     disp(app.conTPL_backwardTM);
%                     disp(app.conTPL_forwardTM);
                    %%%%%%%%%%%%%%%%%%%
                    
                    [backwardEspMatrix, backwardQM, backwardDM, backwardTemp]= preCompQDMatrix(app.conTPL_backwardTM);
                    [forwardEspMatrix, forwardQM, forwardDM, forwardTemp]= preCompQDMatrix(app.conTPL_forwardTM);
                    
                    bpls = [];
                    fpls = [];
                    epsilons = [];
                    tpl = 0;
                    app.conTPL_TPLSteps = [];
                    
                    app.conTPL_time = app.conTPL_time + 1;
                      
                    app.conTPL_backwardStep = calcPL(app, app.conTPL_backwardStep, app.conTPL_EpsSteps(app.conTPL_time), backwardEspMatrix, backwardQM, backwardDM);
                    app.conTPL_forwardStep = calcPL(app, app.conTPL_forwardStep, app.conTPL_EpsSteps(app.conTPL_time), forwardEspMatrix, forwardQM, forwardDM);
                    
                    app.conTPL_BPLSteps = [app.conTPL_BPLSteps app.conTPL_backwardStep];
                    app.conTPL_FPLSteps = [app.conTPL_FPLSteps app.conTPL_forwardStep];
                    
                    bpls = app.conTPL_BPLSteps;
                    fpls = app.conTPL_FPLSteps;
                    fpls = fliplr(fpls);
                    
                    for n = 1: app.conTPL_time
                        tpl = bpls(n) + fpls(n) - app.conTPL_EpsSteps(n);
                        app.conTPL_TPLSteps = [app.conTPL_TPLSteps tpl];
                    end

                    
                    epsilons = app.conTPL_EpsSteps(1:app.conTPL_time);
                    disp('epsilons = ');
                    disp(epsilons);
                   
                    %% limites time based on the input T
                    if app.conTPL_time <= app.conTPL_t
                        times = 1:1:app.conTPL_time;
                    
                        timeRange = 1:1:20;
                        defaultV = zeros(1,20);
                        defaultY = zeros(1, 21);
                        yRange = 0:0.05:1;
            %             app.UIAxes.XTicks([1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20]);
                        app.UIAxes.NextPlot = 'replace';
                        plotX = plot(app.UIAxes, timeRange, defaultV,'-b');
                        hold(app.UIAxes);
                        plotY = plot(app.UIAxes, defaultY, yRange,'-b');
                        conPTLPlot(app,times,bpls,fpls,app.conTPL_TPLSteps,epsilons);
%                         plotBPL = plot(app.UIAxes, times, bpls,'-o');
%                         
%                         plotFPL = plot(app.UIAxes, times, fpls,'-*');
%             
%                         plotTPL = plot(app.UIAxes, times, app.conTPL_TPLSteps,'-^');
%                         plotEps = plot(app.UIAxes, times, epsilons, '--');
%                         set(plotBPL, 'linewidth', 2);
%                         set(plotFPL, 'linewidth', 2);
%                         set(plotTPL, 'linewidth', 2);
%                         set(plotEps, 'linewidth', 2);
%                         lgd = legend(app.UIAxes,[plotBPL plotFPL plotTPL plotEps],'BPL','FPL','TPL','Epsilon');
%                         lgd.FontSize = 15;
                    end
                    
                case 'Method 2'
                    app.MaxTPLEditField_method2.Enable = 'off';
                    if app.conTPL_isFirstRun == 1
                        [app.conTPL_eps, app.conTPL_errCode] = conTPL2(app,app.conTPL_backwardTM, app.conTPL_forwardTM, app.MaxTPLEditField_method2.Value);
                    end
                    
                    disp('-- method 2 --');
                    disp(app.conTPL_eps);
                    
                    if app.conTPL_errCode == 1
                        app.errorMsg.Visible = 'on';
                    else
                        app.errorMsg.Visible = 'off';
                        [backwardEspMatrix_method2, backwardQM_method2, backwardDM_method2, backwardTemp_method2]= preCompQDMatrix(app.conTPL_backwardTM);
                        [forwardEspMatrix_method2, forwardQM_method2, forwardDM_method2, forwardTemp_method2]= preCompQDMatrix(app.conTPL_forwardTM);
                        
                        bpls_method2 = [];
                        fpls_method2 = [];
                        epsilons_method2 = [];
                        tpl_method2 = 0;
                        app.conTPL_TPLSteps = [];
                        
                        app.conTPL_time = app.conTPL_time + 1;
                          
                        app.conTPL_backwardStep = calcPL(app, app.conTPL_backwardStep, app.conTPL_eps, backwardEspMatrix_method2, backwardQM_method2, backwardDM_method2);
                        app.conTPL_forwardStep = calcPL(app, app.conTPL_forwardStep, app.conTPL_eps, forwardEspMatrix_method2, forwardQM_method2, forwardDM_method2);
                        
                        app.conTPL_BPLSteps = [app.conTPL_BPLSteps app.conTPL_backwardStep];
                        app.conTPL_FPLSteps = [app.conTPL_FPLSteps app.conTPL_forwardStep];
                        
                        bpls_method2 = app.conTPL_BPLSteps;
                        fpls_method2 = app.conTPL_FPLSteps;
                        fpls_method2 = fliplr(fpls_method2);
                        
                        for n = 1: app.conTPL_time
                            tpl_method2 = bpls_method2(n) + fpls_method2(n) - app.conTPL_eps;
                            app.conTPL_TPLSteps = [app.conTPL_TPLSteps tpl_method2];
                        end
    
                        app.conTPL_EpsSteps = [app.conTPL_EpsSteps app.conTPL_eps];
                        epsilons_method2 = app.conTPL_EpsSteps;
                        disp('epsilons_method2 = ');
                        disp(epsilons_method2);
                       
                        %% limites time based on the input T
                        
                        times = 1:1:app.conTPL_time;
                    
                        timeRange_method2 = 1:1:20;
                        defaultV = zeros(1,20);
                        defaultY = zeros(1, 21);
                        yRange = 0:0.05:1;
            %             app.UIAxes.XTicks([1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20]);
                        app.UIAxes.NextPlot = 'replace';
                        plotX = plot(app.UIAxes, timeRange_method2, defaultV,'-b');
                        hold(app.UIAxes);
                        plotY = plot(app.UIAxes, defaultY, yRange,'-b');
                        conPTLPlot(app,times,bpls_method2,fpls_method2,app.conTPL_TPLSteps,epsilons);
%                         plotBPL = plot(app.UIAxes, times, bpls_method2,'-o');
%                         
%                         plotFPL = plot(app.UIAxes, times, fpls_method2,'-*');
%             
%                         plotTPL = plot(app.UIAxes, times, app.conTPL_TPLSteps,'-^');
%                         plotEps = plot(app.UIAxes, times, epsilons_method2, '--');
%                         set(plotBPL, 'linewidth', 2);
%                         set(plotFPL, 'linewidth', 2);
%                         set(plotTPL, 'linewidth', 2);
%                         set(plotEps, 'linewidth', 2);
%                         lgd = legend(app.UIAxes,[plotBPL plotFPL plotTPL plotEps],'BPL','FPL','TPL','Epsilon');
%                         lgd.FontSize = 15;
                    end
%                     disp(app.conTPL_backwardTM);
%                     disp(app.conTPL_forwardTM);


% % % % % % % % % % % % % % % % % % % % % % % % % %                     


                case 'Method 3'
                    
                    if app.conTPL_isFirstRun > 1
                        app.conTPL_eps_method3 = conBPL(app, app.conTPL_backwardStep, app.conTPL_backwardTM, app.conTPL_maxBPL_method3);
                    end
                    
                    disp('-- method 3 --');
                    disp(app.conTPL_eps_method3);
%                     disp(app.conTPL_backwardTM);
%                     disp(app.conTPL_forwardTM);
                    
                    
% % % % % % % % % % % % % % % % % % % % % % % % % %                     
                    [backwardEspMatrix, backwardQM, backwardDM, backwardTemp]= preCompQDMatrix(app.conTPL_backwardTM);
                    [forwardEspMatrix, forwardQM, forwardDM, forwardTemp]= preCompQDMatrix(app.conTPL_forwardTM);
                    
                    bpls = [];
                    fpls = [];
                    epsilons = [];
                    tpl = 0;
                    app.conTPL_TPLSteps = [];
                    
                    app.conTPL_time = app.conTPL_time + 1;
                      
                    app.conTPL_backwardStep = calcPL(app, app.conTPL_backwardStep, app.conTPL_eps_method3, backwardEspMatrix, backwardQM, backwardDM);
                    app.conTPL_forwardStep = calcPL(app, app.conTPL_forwardStep, app.conTPL_eps_method3, forwardEspMatrix, forwardQM, forwardDM);
                    
                    app.conTPL_BPLSteps = [app.conTPL_BPLSteps app.conTPL_backwardStep];
                    app.conTPL_FPLSteps = [app.conTPL_FPLSteps app.conTPL_forwardStep];
                    
                    bpls = app.conTPL_BPLSteps;
                    fpls = app.conTPL_FPLSteps;
                    fpls = fliplr(fpls);
                    
                    for n = 1: app.conTPL_time
                        tpl = bpls(n) + fpls(n) - app.conTPL_eps_method3;
                        app.conTPL_TPLSteps = [app.conTPL_TPLSteps tpl];
                    end

                    app.conTPL_EpsSteps = [app.conTPL_EpsSteps app.conTPL_eps];
                    epsilons = app.conTPL_EpsSteps;
                    disp('epsilons_method3 = ');
                    disp(epsilons);
                   
                    %% limites time based on the input T
                    
                    times = 1:1:app.conTPL_time;
                
                    timeRange_method2 = 1:1:20;
                    defaultV = zeros(1,20);
                    defaultY = zeros(1, 21);
                    yRange = 0:0.05:1;
        %             app.UIAxes.XTicks([1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20]);
                    app.UIAxes.NextPlot = 'replace';
                    plotX = plot(app.UIAxes, timeRange_method2, defaultV,'-b');
                    hold(app.UIAxes);
                    plotY = plot(app.UIAxes, defaultY, yRange,'-b');
                    conPTLPlot(app,times,bpls,fpls,app.conTPL_TPLSteps,epsilons);
%                     plotBPL = plot(app.UIAxes, times, bpls,'-o');
%                     
%                     plotFPL = plot(app.UIAxes, times, fpls,'-*');
%         
%                     plotTPL = plot(app.UIAxes, times, app.conTPL_TPLSteps,'-^');
%                     plotEps = plot(app.UIAxes, times, epsilons, '--');
%                     set(plotBPL, 'linewidth', 2);
%                     set(plotFPL, 'linewidth', 2);
%                     set(plotTPL, 'linewidth', 2);
%                     set(plotEps, 'linewidth', 2);
%                     lgd = legend(app.UIAxes,[plotBPL plotFPL plotTPL plotEps],'BPL','FPL','TPL','Epsilon');
%                     lgd.FontSize = 15;
                    
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            app.conTPL_radioButtonsIdentifier = selectedButton.Text;
            disp('app.conTPL_radioButtonsIdentifier-->');
            disp(selectedButton);
            switch selectedButton.Text
                case 'Method 1'
                    
                    app.MaxTPLEditField_method1.Enable = 'on';
                    app.TEditField.Enable = 'on';
                    app.MaxTPLEditField_method2.Enable = 'off';
                    app.MaxBPLtEditField_method3.Enable = 'off';
                case 'Method 2'
                    app.MaxTPLEditField_method1.Enable = 'off';
                    app.TEditField.Enable = 'off';
                    app.MaxTPLEditField_method2.Enable = 'on';
                    app.MaxBPLtEditField_method3.Enable = 'off';
                case 'Method 3'
                    app.MaxTPLEditField_method1.Enable = 'off';
                    app.TEditField.Enable = 'off';
                    app.MaxTPLEditField_method2.Enable = 'off';
                    app.MaxBPLtEditField_method3.Enable = 'on';
            end
%             disp('Radio buttons--->');
%             disp(selectedButton);
%             disp(selectedButton.Value);
%             disp(selectedButton.Text);
        end

        % Value changed function: MaxTPLEditField_method1
        function MaxTPLEditField_method1ValueChanged(app, event)
            value = app.MaxTPLEditField_method1.Value;
            app.conTPL_maxTPL_method1 = value;
        end

        % Value changed function: MaxTPLEditField_method2
        function MaxTPLEditField_method2ValueChanged(app, event)
            value = app.MaxTPLEditField_method2.Value;
            app.conTPL_maxTPL_method2 = value;
        end

        % Value changed function: MaxBPLtEditField_method3
        function MaxBPLtEditField_method3ValueChanged(app, event)
            value = app.MaxBPLtEditField_method3.Value;
            app.conTPL_maxBPL_method3 = value;
        end

        % Value changed function: TEditField
        function TEditFieldValueChanged(app, event)
            value = app.TEditField.Value;
            app.conTPL_t = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'ConTPL: Controlling Temporal Privacy Leakage in Differentially Private Continuous Data Release';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Privacy Loss')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.UIAxes.XTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'};
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.ZTick = [0 0.5 1];
            app.TabGroup = uitabgroup(app.UIFigure);
            app.QutificationTab = uitab(app.TabGroup);
            app.QutificationTab.Title = 'Qutification';
            app.DefaultPanel = uipanel(app.QutificationTab);
            app.DefaultPanel.Title = 'Default';
            app.NexttButton = uibutton(app.DefaultPanel, 'push');
            app.NexttButton.ButtonPushedFcn = createCallbackFcn(app, @NexttButtonPushed, true);
            app.NexttButton.Text = 'Next t';
            app.Runto20Button = uibutton(app.DefaultPanel, 'push');
            app.Runto20Button.ButtonPushedFcn = createCallbackFcn(app, @Runto20ButtonPushed, true);
            app.Runto20Button.Text = 'Run to 20';
            app.BackwardTMLabel = uilabel(app.DefaultPanel);
            app.BackwardTMLabel.Text = 'Backward TM';
            app.TMDropDown_backward = uidropdown(app.DefaultPanel);
            app.TMDropDown_backward.Items = {'TM1', 'TM2', 'TM3'};
            app.TMDropDown_backward.ItemsData = {'0', '0.01', '0.1'};
            app.TMDropDown_backward.ValueChangedFcn = createCallbackFcn(app, @TMDropDown_backwardValueChanged, true);
            app.TMDropDown_backward.Value = '0';
            app.ForwardTMLabel = uilabel(app.DefaultPanel);
            app.ForwardTMLabel.Text = 'Forward TM';
            app.TMDropDown_forward = uidropdown(app.DefaultPanel);
            app.TMDropDown_forward.Items = {'TM1', 'TM2', 'TM3'};
            app.TMDropDown_forward.ItemsData = {'0', '0.01', '0.1'};
            app.TMDropDown_forward.ValueChangedFcn = createCallbackFcn(app, @TMDropDown_forwardValueChanged, true);
            app.TMDropDown_forward.Value = '0';
            app.EpsilonattEditFieldLabel = uilabel(app.DefaultPanel);
            app.EpsilonattEditFieldLabel.Text = 'Epsilon at t';
            app.EpsilonattEditField = uieditfield(app.DefaultPanel, 'numeric');
            app.EpsilonattEditField.ValueChangedFcn = createCallbackFcn(app, @EpsilonattEditFieldValueChanged, true);
            app.EpsilonattEditField.Value = 0.1;
            app.backwardTM_label = uilabel(app.DefaultPanel);
            app.backwardTM_label.Text = 'TM1:  Strong Temporal Correlation';
            app.forwardTM_label = uilabel(app.DefaultPanel);
            app.forwardTM_label.Text = 'TM1:  Strong Temporal Correlation';
            app.CustomizedPanel = uipanel(app.QutificationTab);
            app.CustomizedPanel.Title = 'Customized';
            app.NextTimeButton = uibutton(app.CustomizedPanel, 'push');
            app.NextTimeButton.ButtonPushedFcn = createCallbackFcn(app, @NextTimeButtonPushed, true);
            app.NextTimeButton.Text = 'Next Time';
            app.Runto20sButton = uibutton(app.CustomizedPanel, 'push');
            app.Runto20sButton.ButtonPushedFcn = createCallbackFcn(app, @Runto20sButtonPushed, true);
            app.Runto20sButton.Text = 'Run to 20s';
            app.BackwardSEditFieldLabel = uilabel(app.CustomizedPanel);
            app.BackwardSEditFieldLabel.Text = 'Backward S';
            app.BackwardSEditField = uieditfield(app.CustomizedPanel, 'numeric');
            app.BackwardSEditField.Limits = [0 Inf];
            app.BackwardSEditField.ValueChangedFcn = createCallbackFcn(app, @BackwardSEditFieldValueChanged, true);
            app.BackwardNEditFieldLabel = uilabel(app.CustomizedPanel);
            app.BackwardNEditFieldLabel.Text = 'Backward N';
            app.BackwardNEditField = uieditfield(app.CustomizedPanel, 'numeric');
            app.BackwardNEditField.Limits = [0 Inf];
            app.BackwardNEditField.ValueChangedFcn = createCallbackFcn(app, @BackwardSEditFieldValueChanged, true);
            app.BackwardNEditField.Value = 30;
            app.EpsilonattEditField_2Label = uilabel(app.CustomizedPanel);
            app.EpsilonattEditField_2Label.Text = 'Epsilon at t';
            app.CusEpsilonattEditField = uieditfield(app.CustomizedPanel, 'numeric');
            app.CusEpsilonattEditField.Limits = [0 Inf];
            app.CusEpsilonattEditField.ValueChangedFcn = createCallbackFcn(app, @CusEpsilonattEditFieldValueChanged, true);
            app.CusEpsilonattEditField.Value = 0.1;
            app.ForwardSEditFieldLabel = uilabel(app.CustomizedPanel);
            app.ForwardSEditFieldLabel.Text = 'Forward S';
            app.ForwardSEditField = uieditfield(app.CustomizedPanel, 'numeric');
            app.ForwardSEditField.Limits = [0 Inf];
            app.ForwardSEditField.ValueChangedFcn = createCallbackFcn(app, @BackwardSEditFieldValueChanged, true);
            app.ForwardNEditFieldLabel = uilabel(app.CustomizedPanel);
            app.ForwardNEditFieldLabel.Text = 'Forward N';
            app.ForwardNEditField = uieditfield(app.CustomizedPanel, 'numeric');
            app.ForwardNEditField.Limits = [0 Inf];
            app.ForwardNEditField.ValueChangedFcn = createCallbackFcn(app, @BackwardSEditFieldValueChanged, true);
            app.ForwardNEditField.Value = 30;
            app.ConTPLTab = uitab(app.TabGroup);
            app.ConTPLTab.Title = 'ConTPL';
            app.BackwardTMDropDownLabel = uilabel(app.ConTPLTab);
            app.BackwardTMDropDownLabel.Text = 'Backward TM';
            app.BackwardTMDropDown = uidropdown(app.ConTPLTab);
            app.BackwardTMDropDown.Items = {'TM1', 'TM2', 'TM3'};
            app.BackwardTMDropDown.ItemsData = {'0', '0.01', '0.1'};
            app.BackwardTMDropDown.ValueChangedFcn = createCallbackFcn(app, @BackwardTMDropDownValueChanged, true);
            app.BackwardTMDropDown.Value = '0';
            app.ForwardTMDropDownLabel = uilabel(app.ConTPLTab);
            app.ForwardTMDropDownLabel.Text = 'Forward TM';
            app.ForwardTMDropDown = uidropdown(app.ConTPLTab);
            app.ForwardTMDropDown.Items = {'TM1', 'TM2', 'TM3'};
            app.ForwardTMDropDown.ItemsData = {'0', '0.01', '0.1'};
            app.ForwardTMDropDown.ValueChangedFcn = createCallbackFcn(app, @ForwardTMDropDownValueChanged, true);
            app.ForwardTMDropDown.Value = '0';
            app.NextButton = uibutton(app.ConTPLTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ButtonGroup = uibuttongroup(app.ConTPLTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.Method1Button = uiradiobutton(app.ButtonGroup);
            app.Method1Button.Text = 'Method 1';
            app.Method1Button.Value = true;
            app.Method2Button = uiradiobutton(app.ButtonGroup);
            app.Method2Button.Text = 'Method 2';
            app.Method3Button = uiradiobutton(app.ButtonGroup);
            app.Method3Button.Text = 'Method 3';
            app.MaxTPLEditFieldLabel = uilabel(app.ButtonGroup);
            app.MaxTPLEditFieldLabel.Text = 'Max TPL';
            app.MaxTPLEditField_method1 = uieditfield(app.ButtonGroup, 'numeric');
            app.MaxTPLEditField_method1.Limits = [0 Inf];
            app.MaxTPLEditField_method1.ValueChangedFcn = createCallbackFcn(app, @MaxTPLEditField_method1ValueChanged, true);
            app.MaxTPLEditField_method1.Value = 1;
            app.TEditFieldLabel = uilabel(app.ButtonGroup);
            app.TEditFieldLabel.Text = 'T';
            app.TEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.TEditField.Limits = [0 Inf];
            app.TEditField.ValueDisplayFormat = '%.0f';
            app.TEditField.ValueChangedFcn = createCallbackFcn(app, @TEditFieldValueChanged, true);
            app.TEditField.Value = 20;
            app.MaxTPLEditField_2Label = uilabel(app.ButtonGroup);
            app.MaxTPLEditField_2Label.Text = 'Max TPL';
            app.MaxTPLEditField_method2 = uieditfield(app.ButtonGroup, 'numeric');
            app.MaxTPLEditField_method2.Limits = [0 Inf];
            app.MaxTPLEditField_method2.ValueChangedFcn = createCallbackFcn(app, @MaxTPLEditField_method2ValueChanged, true);
            app.MaxTPLEditField_method2.Enable = 'off';
            app.MaxTPLEditField_method2.Value = 1;
            app.MaxBPLtEditFieldLabel = uilabel(app.ButtonGroup);
            app.MaxBPLtEditFieldLabel.Text = 'Max BPLt';
            app.MaxBPLtEditField_method3 = uieditfield(app.ButtonGroup, 'numeric');
            app.MaxBPLtEditField_method3.Limits = [0 Inf];
            app.MaxBPLtEditField_method3.ValueChangedFcn = createCallbackFcn(app, @MaxBPLtEditField_method3ValueChanged, true);
            app.MaxBPLtEditField_method3.Enable = 'off';
            app.MaxBPLtEditField_method3.Value = 1;
            app.TM1StrongTemporalCorrelationLabel = uilabel(app.ConTPLTab);
            app.TM1StrongTemporalCorrelationLabel.Text = 'TM1:  Strong Temporal Correlation';
            app.TM1StrongTemporalCorrelationLabel_2 = uilabel(app.ConTPLTab);
            app.TM1StrongTemporalCorrelationLabel_2.Text = 'TM1:  Strong Temporal Correlation';
            app.errorMsg = uilabel(app.ConTPLTab);
            app.errorMsg.Visible = 'off';
            app.errorMsg.Text = 'Sorry, method 2 does not work for such TM & maxTPL';
            app.ClearAllButton = uibutton(app.UIFigure, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
        end
    end

    methods (Access = public)

        % Construct app
        function app = conTPL

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end